classdef InclinometerComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        InclinometerStepperMotorControllerPanel  matlab.ui.container.Panel
        SetCurrentPositiontoStartButton  matlab.ui.control.Button
        DirectionSwitch            matlab.ui.control.Switch
        MoveToStartButton          matlab.ui.control.Button
        MoveXMillimetersButton     matlab.ui.control.Button
        MoveXMillimetersEditField  matlab.ui.control.EditField
    end

    methods (Access = public)
        function ret_signal = move_x_mm(comp, dis_mm)
            % global app.arduino_serial;
            arduino_serial = evalin('base', 'arduino_serial');
            MOVE_X = 4;
            serial_string = strcat(num2str(MOVE_X),",",num2str(dis_mm));
            %disp(serial_string);
            ret_signal = serial_communication(comp, arduino_serial, serial_string);
            flush(arduino_serial);
            disp(ret_signal);
            % disp('in move x mm');
            % disp(dis_mm);
        end

        function ret_mm = return_to_start(comp)
            arduino_serial = evalin('base', 'arduino_serial');
            MOVE_TO_START = 2;
            serial_string = strcat(num2str(MOVE_TO_START),",0,1");
            ret_mm = serial_communication(comp, arduino_serial, serial_string);
            flush(arduino_serial);
        end

        function ret_signal = set_current_position(comp, pos)
            SET_CURRENT_POS = 6;
            arduino_serial = evalin('base', 'arduino_serial');
            serial_string = strcat(num2str(SET_CURRENT_POS),",",num2str(pos),",0");
            %ret_signal = serial_communication(s, serial_string);
            custom_write(arduino_serial, serial_string);
            ret_signal = custom_read(arduino_serial);
            flush(arduino_serial);
        end

        function sig = serial_communication(comp, s, message)
            custom_write(comp, s,message);
            pause(1);
            disp("wait");
            waitfor(s, "NumBytesAvailable");
            sig = custom_read(comp, s);
        end

        function custom_write(comp, s, message)
            flush(s);
            writeline(s, message);
        end

        function out = custom_read(comp, s)
            out = readline(s);
            flush(s);
        end
 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MoveXMillimetersButton
        function move_x_milimeters(comp, event)
            try
                direction_choice = str2num(comp.DirectionSwitch.Value);
                % disp(direction_choice);
                millimeters = direction_choice*abs(str2num(comp.MoveXMillimetersEditField.Value));
                % disp(millimeters);
                move_x_mm(comp, millimeters);
            catch e
                fig = uifigure;
                m = getReport(e,"exxtended");
                uialert(fig,m,'Inclinometer Controller Error');
            end
        end

        % Button pushed function: MoveToStartButton
        function move_to_start(comp, event)
            try
                return_to_start(comp);
            catch 
                fig = uifigure;
                m = getReport(e,"exxtended");
                uialert(fig,m,'Inclinometer Controller Error');
            end
        end

        % Button pushed function: SetCurrentPositiontoStartButton
        function SetCurrentPositiontoStartButtonPushed(comp, event)
            try
                set_current_position(comp, 0);
            catch e          
                fig = uifigure;
                m = getReport(e,"exxtended");
                uialert(fig,m,'Inclinometer Controller Error');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 338 182];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.InclinometerStepperMotorControllerPanel = uipanel(comp);
            comp.InclinometerStepperMotorControllerPanel.Title = 'Inclinometer Stepper Motor Controller';
            comp.MoveXMillimetersEditField = uieditfield(comp.InclinometerStepperMotorControllerPanel, 'text');
            comp.MoveXMillimetersEditField.Placeholder = 'Millimeters to Move';
            comp.MoveXMillimetersButton = uibutton(comp.InclinometerStepperMotorControllerPanel, 'push');
            comp.MoveXMillimetersButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @move_x_milimeters, true);
            comp.MoveXMillimetersButton.Text = 'Move X Millimeters';
            comp.MoveToStartButton = uibutton(comp.InclinometerStepperMotorControllerPanel, 'push');
            comp.MoveToStartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @move_to_start, true);
            comp.MoveToStartButton.Text = 'Move To Start';
            comp.DirectionSwitch = uiswitch(comp.InclinometerStepperMotorControllerPanel, 'slider');
            comp.DirectionSwitch.Items = {'To Front', 'To Back'};
            comp.DirectionSwitch.ItemsData = {'1', '-1'};
            comp.DirectionSwitch.Value = '1';
            comp.SetCurrentPositiontoStartButton = uibutton(comp.InclinometerStepperMotorControllerPanel, 'push');
            comp.SetCurrentPositiontoStartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetCurrentPositiontoStartButtonPushed, true);
            comp.SetCurrentPositiontoStartButton.Text = 'Set Current Position to Start';
        end
    end
end