classdef SetCOMs < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        SetCOMsPanel                   matlab.ui.container.Panel
        DropDownForceRight             matlab.ui.control.DropDown
        DropDownForceLeft              matlab.ui.control.DropDown
        DropDownInclBack               matlab.ui.control.DropDown
        DropDownInclFront              matlab.ui.control.DropDown
        DropDownArduino                matlab.ui.control.DropDown
        ClearCOMPortsButton            matlab.ui.control.Button
        SetForceGaugeRightCOMButton    matlab.ui.control.Button
        SetForceGaugeLeftCOMButton     matlab.ui.control.Button
        SetInclinomterBackCOMButton    matlab.ui.control.Button
        SetInclinometerFrontCOMButton  matlab.ui.control.Button
        SetArduinoCOMButton            matlab.ui.control.Button
    end

    properties (Access = public)
        App
    end
    
    
    methods (Access = public)

        %function obj = SetCOMs(app)
        %    obj.App = app;
        %end
        
        % function value = getArduino(obj, propertyName)
        %     value = obj.App.(propertyName);
        % end
        
        % function setArduino(obj, propertyName, value)
        %     obj.App.Arduino = value;
        % end
        % 
        % function setInclinometerFront(obj, propertyName, value)
        %     obj.App.Arduino = value;
        % end
        % 
        % function setInclinometerBack(obj, propertyName, value)
        %     obj.App.Arduino = value;
        % end
        % 
        % function setForceLeft(obj, propertyName, value)
        %     obj.App.Arduino = value;
        % end
        % 
        % function setForceRight(obj, propertyName, value)
        %     obj.App.Arduino = value;
        % end
        
        function results = setSerial(comp, serial, port, baud)

            try
               clear serial;
               serial = serialport(port, baud);
               %disp("serail set");
            catch e
               str = strcat("failed to connect serial. \n Exception: \n ", e);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DropDownArduino
        function DropDownArduinoOpening(comp, event)
            % set all drop down options to avalible com ports
            freeports = serialportlist("available");
%             assignin('base','freeports',freeports);
%             disp(class(freeports));
%             disp(freeports);
            comp.DropDownArduino.Items = freeports;
        end

        % Button pushed function: SetArduinoCOMButton
        function SetArduinoCOMButtonPushed(comp, event)
            %global arduino_serail;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownArduino.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownArduino.Placeholder)
                %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
               return;
            end
            TestingUI.arduino_port = selectedCOM;
            %disp("option not selected, comport coing to be set");
            setSerial(comp, TestingUI.arduino_serial, selectedCOM, 115200);
        end

        % Button pushed function: SetInclinometerFrontCOMButton
        function SetInclinometerFrontCOMButtonPushed(comp, event)
            %global inclinometer_front_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownInclFront.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownInclFront.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            TestingUI.inclinometer_port_front = selectedCOM;
            setSerial(comp, TestingUI.inclinometer_front_serial, selectedCOM, 9600);
        end

        % Button pushed function: SetInclinomterBackCOMButton
        function SetInclinomterBackCOMButtonPushed(comp, event)
            %global inclinometer_back_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownInclBack.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownInclBack.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            TestingUI.inclinometer_port_back = selectedCOM;
            setSerial(comp, TestingUI.inclinometer_back_serial, selectedCOM, 9600);
        end

        % Button pushed function: SetForceGaugeLeftCOMButton
        function SetForceGaugeLeftCOMButtonPushed(comp, event)
            %global force_gage_left_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownForceLeft.Value;
            disp(class(selectedCOM));
            disp(class(comp.DropDownForceLeft.Placeholder));
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownForceLeft.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            TestingUI.force_gage1_port = selectedCOM;
            setSerial(comp, TestingUI.force_gage_left_serial, selectedCOM, 9600);
        end

        % Button pushed function: SetForceGaugeRightCOMButton
        function SetForceGaugeRightCOMButtonPushed(comp, event)
            %global force_gage_right_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownForceRight.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownForceRight.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            TestingUI.force_gage2_port = selectedCOM;
            setSerial(comp, TestingUI.force_gage_right_serial, selectedCOM, 9600);
        end

        % Button pushed function: ClearCOMPortsButton
        function ClearCOMPortsButtonPushed(comp, event)

            clear TestingUI.arduino_serial TestingUI.inclinometer_front_serial TestingUI.inclinometer_back_serial TestingUI.force_gage_left_serial TestingUI.force_gage_right_serial;
        end

        % Drop down opening function: DropDownInclFront
        function DropDownInclFrontOpening(comp, event)
            freeports = serialportlist("available");
%             assignin('base','freeports',freeports);
%             disp(class(freeports));
%             disp(freeports);
            comp.DropDownInclFront.Items = freeports;
        end

        % Drop down opening function: DropDownInclBack
        function DropDownInclBackOpening(comp, event)
            freeports = serialportlist("available");
%             assignin('base','freeports',freeports);
%             disp(class(freeports));
%             disp(freeports);
            comp.DropDownInclBack.Items = freeports;
        end

        % Drop down opening function: DropDownForceLeft
        function DropDownForceLeftOpening(comp, event)
            
        end

        % Drop down opening function: DropDownForceRight
        function DropDownForceRightOpening(comp, event)
            freeports = serialportlist("available");
%             assignin('base','freeports',freeports);
%             disp(class(freeports));
%             disp(freeports);
            comp.DropDownForceRight.Items = freeports;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            freeports = serialportlist("available");
%             assignin('base','freeports',freeports);
%             disp(class(freeports));
%             disp(freeports);
            comp.DropDownForceLeft.Items = freeports;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 345 247];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.SetCOMsPanel = uipanel(comp);
            comp.SetCOMsPanel.Title = 'Set COMs';
            comp.SetArduinoCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetArduinoCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetArduinoCOMButtonPushed, true);
            comp.SetArduinoCOMButton.Text = 'Set Arduino COM';
            comp.SetInclinometerFrontCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetInclinometerFrontCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetInclinometerFrontCOMButtonPushed, true);
            comp.SetInclinometerFrontCOMButton.Text = 'Set Inclinometer Front COM';
            comp.SetInclinomterBackCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetInclinomterBackCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetInclinomterBackCOMButtonPushed, true);
            comp.SetInclinomterBackCOMButton.Text = 'Set Inclinomter Back COM';
            comp.SetForceGaugeLeftCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetForceGaugeLeftCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetForceGaugeLeftCOMButtonPushed, true);
            comp.SetForceGaugeLeftCOMButton.Text = 'Set Force Gauge Left COM';
            comp.SetForceGaugeRightCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetForceGaugeRightCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetForceGaugeRightCOMButtonPushed, true);
            comp.SetForceGaugeRightCOMButton.Text = 'Set Force Gauge Right COM';
            comp.ClearCOMPortsButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.ClearCOMPortsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ClearCOMPortsButtonPushed, true);
            comp.ClearCOMPortsButton.Text = 'Clear COM Ports';
            comp.DropDownArduino = uidropdown(comp.SetCOMsPanel);
            comp.DropDownArduino.Items = {'Options'};
            comp.DropDownArduino.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownArduinoOpening, true);
            comp.DropDownArduino.Placeholder = 'Options';
            comp.DropDownArduino.Value = 'Options';
            comp.DropDownInclFront = uidropdown(comp.SetCOMsPanel);
            comp.DropDownInclFront.Items = {'Options'};
            comp.DropDownInclFront.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownInclFrontOpening, true);
            comp.DropDownInclFront.Placeholder = 'Options';
            comp.DropDownInclFront.Value = 'Options';
            comp.DropDownInclBack = uidropdown(comp.SetCOMsPanel);
            comp.DropDownInclBack.Items = {'Options'};
            comp.DropDownInclBack.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownInclBackOpening, true);
            comp.DropDownInclBack.Placeholder = 'Options';
            comp.DropDownInclBack.Value = 'Options';
            comp.DropDownForceLeft = uidropdown(comp.SetCOMsPanel);
            comp.DropDownForceLeft.Items = {'Options'};
            comp.DropDownForceLeft.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownForceLeftOpening, true);
            comp.DropDownForceLeft.Placeholder = 'Options';
            comp.DropDownForceLeft.Value = 'Options';
            comp.DropDownForceRight = uidropdown(comp.SetCOMsPanel);
            comp.DropDownForceRight.Items = {'Options'};
            comp.DropDownForceRight.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownForceRightOpening, true);
            comp.DropDownForceRight.Placeholder = 'Options';
            comp.DropDownForceRight.Value = 'Options';
        end
    end
end